classdef lidarApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ReadDataButton                matlab.ui.control.Button
        ConnecttoPICButton            matlab.ui.control.Button
        RoomVisualizationButton       matlab.ui.control.Button
        PICConnectionStatusLamp       matlab.ui.control.Lamp
        PICConnectionStatusLampLabel  matlab.ui.control.Label
        SerialPortEditField           matlab.ui.control.EditField
        SerialPortEditFieldLabel      matlab.ui.control.Label
        BaudRateEditField             matlab.ui.control.NumericEditField
        BaudRateEditFieldLabel        matlab.ui.control.Label
        LiDARMeasurementsWithPIC18F87K22Label  matlab.ui.control.Label
        SendCommandsButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        device % Serial port device
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnecttoPICButton
        function ConnecttoPICButtonPushed(app, event)
            baud = app.BaudRateEditField.Value;
            port = app.SerialPortEditField.Value;
            
            try
                % Attempt to connect
                app.device = serialport(port, baud);

                % Successful connection
                app.PICConnectionStatusLampLabel.Text = "Connection Successful!";
                app.PICConnectionStatusLamp.Color = 'g';    % Green lamp

            catch
                % Connection failed
                app.PICConnectionStatusLampLabel.Text = "Connection Failed!";
                app.PICConnectionStatusLamp.Color = 'r';    % Red lamp
            end


        end

        % Button pushed function: SendCommandsButton
        function SendCommandsButtonPushed(app, event)
            sendComm(app.device);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SendCommandsButton = uibutton(app.UIFigure, 'push');
            app.SendCommandsButton.ButtonPushedFcn = createCallbackFcn(app, @SendCommandsButtonPushed, true);
            app.SendCommandsButton.Text = 'Send Commands';
            app.LiDARMeasurementsWithPIC18F87K22Label = uilabel(app.UIFigure);
            app.LiDARMeasurementsWithPIC18F87K22Label.Text = 'LiDAR Measurements With PIC18F87K22';
            app.BaudRateEditFieldLabel = uilabel(app.UIFigure);
            app.BaudRateEditFieldLabel.Text = 'Baud Rate';
            app.BaudRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.BaudRateEditField.ValueDisplayFormat = '%.0f';
            app.BaudRateEditField.Value = 19200;
            app.SerialPortEditFieldLabel = uilabel(app.UIFigure);
            app.SerialPortEditFieldLabel.Text = 'Serial Port';
            app.SerialPortEditField = uieditfield(app.UIFigure, 'text');
            app.SerialPortEditField.Value = 'COM4';
            app.PICConnectionStatusLampLabel = uilabel(app.UIFigure);
            app.PICConnectionStatusLampLabel.Text = 'PIC Connection Status';
            app.PICConnectionStatusLamp = uilamp(app.UIFigure);
            app.RoomVisualizationButton = uibutton(app.UIFigure, 'push');
            app.RoomVisualizationButton.Text = 'Room Visualization';
            app.ConnecttoPICButton = uibutton(app.UIFigure, 'push');
            app.ConnecttoPICButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoPICButtonPushed, true);
            app.ConnecttoPICButton.Text = 'Connect to PIC';
            app.ReadDataButton = uibutton(app.UIFigure, 'push');
            app.ReadDataButton.Text = 'Read Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lidarApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end